classdef FitApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure                   % FitApp - ...
        AxesClearButton      matlab.ui.control.Button           % Clear Both
        AxesSaveButton       matlab.ui.control.Button           % Save Plots
        FitAxes              matlab.ui.control.UIAxes           % Title
        FitCearButton        matlab.ui.control.Button           % Clear
        FitCopyButton        matlab.ui.control.Button           % Copy
        ResCopyButton        matlab.ui.control.Button           % Copy
        ResAxes              matlab.ui.control.UIAxes           % Title Res...
        ResCearButton        matlab.ui.control.Button           % Clear
        FitArgumentsPanel    matlab.ui.container.Panel          % Fit Argum...
        FuncArgsText         matlab.ui.control.EditField        % a(1) a(2)...
        FuncPlotButton       matlab.ui.control.Button           % Plot
        FuncCheckBox         matlab.ui.control.CheckBox         % Custom Fu...
        RangeMaxText         matlab.ui.control.NumericEditField % [-Inf Inf]
        RangeMinText         matlab.ui.control.NumericEditField % [-Inf Inf]
        Label                matlab.ui.control.Label           
        RangeCheckBox        matlab.ui.control.CheckBox         % Plot's Range
        MarkCheckBox         matlab.ui.control.CheckBox         % Mark Points
        MarkText             matlab.ui.control.EditField        % n(1) n(2)...
        LegendsCheckBox      matlab.ui.control.CheckBox         % Legends
        LegendsDropDown      matlab.ui.control.DropDown         % Text, TeX...
        LegendsText          matlab.ui.control.EditField        % y(x); f(x...
        FuncText             matlab.ui.control.TextArea         % a(1) + a(...
        DataPanel            matlab.ui.container.Panel          % Data
        PasteDataButton      matlab.ui.control.Button           % Paste Data
        OpenFileButton       matlab.ui.control.Button           % Open File
        OpenFileText         matlab.ui.control.EditField        % Path \ Fi...
        PlotButtonsPanel     matlab.ui.container.Panel          % Plot
        PlotAllButton        matlab.ui.control.Button           % Plot Data...
        PlotDataButton       matlab.ui.control.Button           % Plot Data
        CalculateFitButton   matlab.ui.control.Button           % Calculate...
        PlotFitButton        matlab.ui.control.Button           % Plot Fit
        PlotResidualsButton  matlab.ui.control.Button           % Plot Resi...
        ClearAxesState       matlab.ui.control.StateButton      % Clear Always
        ColorPickPanel       matlab.ui.container.Panel          % Colors
        Label2               matlab.ui.control.Label            % Fit
        Label4               matlab.ui.control.Label            % Errors
        Label6               matlab.ui.control.Label            % Marker
        Label7               matlab.ui.control.Label            % Points
        errorColorPickButton matlab.ui.control.Button          
        dotColorPickButton   matlab.ui.control.Button          
        markColorPickButton  matlab.ui.control.Button          
        fitColorPickButton   matlab.ui.control.Button          
        TitlesPanel          matlab.ui.container.Panel          % Titles
        TitleText            matlab.ui.control.EditField        % Title
        TitleDropDown        matlab.ui.control.DropDown         % None, TeX
        xTitleText           matlab.ui.control.EditField        % x
        yTitleText           matlab.ui.control.EditField        % y
        yResTitleText        matlab.ui.control.EditField        % y Residuals
        OutputPanel          matlab.ui.container.Panel          % Output
        OutputCopyButton     matlab.ui.control.Button           % Copy
        OutputText           matlab.ui.control.TextArea        
        Label8               matlab.ui.control.Label            % χ² reduced =
        Label9               matlab.ui.control.Label            % p-value  ...
        chiLabel             matlab.ui.control.Label            % 1
        pvalueLabel          matlab.ui.control.Label            % 0.5
        OutputRelText        matlab.ui.control.TextArea        
        StatusPanel          matlab.ui.container.Panel         
        StatusLabel          matlab.ui.control.Label            % Status
        StatusTextPanel      matlab.ui.container.Panel         
        StatusTextLabel      matlab.ui.control.Label           
        Panel2               matlab.ui.container.Panel         
        Label11              matlab.ui.control.Label            % Tor Hadas
        Panel                matlab.ui.container.Panel         
        AboutButton          matlab.ui.control.Button           % 1.0
    end


    properties (Access = public)
        Data % used to store the data
        Colors % Description
        Save % Description
        Func % stores all outer functions
        Status %used to store warnings
    end

    methods (Access = private)
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Status.Warning = '';
            app.Func = GetFunctions;
            app.Colors.Error = [0,0,1];
            app.Colors.Fit = [1,0,0];
            app.Colors.Dot = [0,0,0];
            app.Colors.Mark = [0,1,0];
            app.Colors.Background = [240/255,240/255,240/255];
            app.fitColorPickButton.BackgroundColor = app.Colors.Fit;
            app.dotColorPickButton.BackgroundColor = app.Colors.Dot;
            app.errorColorPickButton.BackgroundColor = app.Colors.Error;
            app.markColorPickButton.BackgroundColor = app.Colors.Mark;
            app.Data.Output = 'No Data';
            app.Save.FitFig = figure('Visible','off');
            app.Save.ResFig = figure('Visible','off');
            app.Save.FitAxes = axes(app.Save.FitFig);
            app.Save.ResAxes = axes(app.Save.ResFig);
            app.Save.Directory = sprintf('%s\\Desktop\\', getenv('USERPROFILE'));
            app.Data.File = sprintf('%s\\Data.txt', getenv('USERPROFILE'));
        end

        % FuncCheckBox value changed function
        function FuncCheckBox_Changed(app)
            value = app.FuncCheckBox.Value;
            if value
                app.FuncText.Enable = 'on';
                app.FuncArgsText.Enable = 'on';
                app.FuncPlotButton.Enable = 'on';
            else
                app.FuncText.Enable = 'off';
                app.FuncArgsText.Enable = 'off';
                app.FuncPlotButton.Enable = 'off ';
            end
        end

        % PasteDataButton button pushed function
        function PasteDataButton_Clicked(app)
            app.Func.PasteData(app);
        end

        % FuncPlotButton button pushed function
        function FuncPlotButton_Clicked(app)
            if app.Func.PlotFuncFcn(app)
                app.Func.SetDone(app);
            end
        end

        % TitleText value changed function
        function TitleText_Typed(app)
            value = app.TitleText.Value;
            app.Func.titleFit(app, value);
            app.Func.titleRes(app, [value, ' Residuals']);
        end

        % xTitleText value changed function
        function xTitleText_Typed(app)
            value = app.xTitleText.Value;
            app.Func.xtitle(app, value);
        end

        % yTitleText value changed function
        function yTitleText_Typed(app)
            value = app.yTitleText.Value;
            app.Func.ytitleFit(app, value);
        end

        % yResTitleText value changed function
        function yResTitleText_Typed(app)
            value = app.yResTitleText.Value;
            app.Func.ytitleRes(app, value);
        end

        % TitleDropDown value changed function
        function TitleDropDown_Changed(app, event)
            value = app.TitleDropDown.Value;
            app.Func.titleFit(app, app.TitleText.Value, 'interpreter', value);
            app.Func.titleRes(app, [app.TitleText.Value, ' Residuals'], 'interpreter', value);
            app.Func.xtitle(app, app.xTitleText.Value, 'interpreter', value);
            app.Func.ytitleFit(app, app.yTitleText.Value, 'interpreter', value);
            app.Func.ytitleRes(app, app.yResTitleText.Value, 'interpreter', value);
        end

        % PlotDataButton button pushed function
        function PlotDataButton_Clicked(app)
            if app.ClearAxesState.Value
                cla(app.FitAxes);
                cla(app.Save.FitAxes);
            end
            if app.Func.PlotDataFcn(app);
                app.Func.SetDone(app);
            end
        end

        % CalculateFitButton button pushed function
        function CalculateFitButton_Clicked(app)
            if app.Func.CalculateFitFcn(app);
                app.Func.SetDone(app);
                if strcmp(app.PlotFitButton.Enable, 'off')
                    app.PlotFitButton.Enable = 'on';
                    app.PlotResidualsButton.Enable = 'on';
                end
            end
        end

        % OutputCopyButton button pushed function
        function OutputCopyButton_Clicked(app)
            clipboard('copy', strjoin(app.Data.Output,'\n'));
        end

        % PlotFitButton button pushed function
        function PlotFitButton_Clicked(app)
            if app.Func.PlotFitFcn(app);
                app.Func.PlotLegends(app);
                app.Func.SetDone(app);
            end
        end

        % FitCearButton button pushed function
        function FitCearButton_Clicked(app)
            cla(app.FitAxes);
            cla(app.Save.FitAxes);
            ylim(app.FitAxes, [0,1]);
            ylim(app.Save.FitAxes, [0,1]);
        end

        % ResCearButton button pushed function
        function ResCearButton_Clicked(app)
            cla(app.ResAxes);
            cla(app.Save.ResAxes);
            ylim(app.FitAxes, [0,1]);
            ylim(app.Save.FitAxes, [0,1]);
        end

        % AxesClearButton button pushed function
        function AxesClearButton_Clicked(app)
            cla(app.FitAxes);
            cla(app.Save.FitAxes);
            cla(app.ResAxes);
            cla(app.Save.ResAxes);
            ylim(app.ResAxes, [0,1]);
            ylim(app.Save.ResAxes, [0,1]);
            ylim(app.FitAxes, [0,1]);
            ylim(app.Save.FitAxes, [0,1]);
        end

        % PlotResidualsButton button pushed function
        function PlotResidualsButton_Clicked(app)
            if app.ClearAxesState.Value
                cla(app.ResAxes);
                cla(app.Save.ResAxes);
            end
            if app.Func.PlotResidualsFcn(app);
                app.Func.SetDone(app);
            end
        end

        % MarkCheckBox value changed function
        function MarkCheckBox_Changed(app)
            value = app.MarkCheckBox.Value;
            if value
                app.MarkText.Enable = 'on';
            else
                app.MarkText.Enable = 'off';
            end
        end

        % LegendsCheckBox value changed function
        function LegendsCheckBox_Changed(app)
            value = app.LegendsCheckBox.Value;
            if value
                app.LegendsDropDown.Enable = 'on';
                app.LegendsText.Enable = 'on';
            else
                app.LegendsDropDown.Enable = 'off';
                app.LegendsText.Enable = 'off';
            end
        end

        % UIFigure close request function
        function closeFcn(app)
            close(app.Save.FitFig);
            close(app.Save.ResFig);
            delete(app);
        end

        % RangeCheckBox value changed function
        function RangeCheckBox_Clicked(app)
            value = app.RangeCheckBox.Value;
            if value
                app.RangeMaxText.Enable = 'on';
                app.RangeMinText.Enable = 'on';
            else
                app.RangeMaxText.Enable = 'off';
                app.RangeMinText.Enable = 'off';
            end
        end

        % fitColorPickButton button pushed function
        function fitColorPickButton_Clicked(app)
            app.Colors.Fit = uisetcolor( app.Colors.Fit);
            app.fitColorPickButton.BackgroundColor = app.Colors.Fit;
        end

        % errorColorPickButton button pushed function
        function errorColorPickButton_Clicked(app)
            app.Colors.Error = uisetcolor( app.Colors.Error);
            app.errorColorPickButton.BackgroundColor = app.Colors.Error;
        end

        % dotColorPickButton button pushed function
        function dotColorPickButton_Clicked(app)
            app.Colors.Dot = uisetcolor(app.Colors.Dot);
            app.dotColorPickButton.BackgroundColor = app.Colors.Dot;
        end

        % markColorPickButton button pushed function
        function markColorPickButton_Clicked(app)
            app.Colors.Mark = uisetcolor(app.Colors.Mark);
            app.markColorPickButton.BackgroundColor = app.Colors.Mark;
        end

        % FitCopyButton button pushed function
        function FitCopyButton_Clicked(app)
            print(app.Save.FitFig,'-dmeta');
        end

        % ResCopyButton button pushed function
        function ResCopyButton_Clicked(app)
            print(app.Save.ResFig,'-dmeta');
        end

        % AxesSaveButton button pushed function
        function AxesSaveButton_Clicked(app)
            app.Func.SetStatus(app, 'Saving plots to .jpg images...');
            directory = uigetdir(app.Save.Directory, 'Choose Directory');
            if isequal(directory , 0)
                return;
            end
            app.Save.Directory = directory;
            filename = '\FitPlot';
            i = 0;
            while exist([directory filename num2str(i) '.jpg'], 'file') ~= 0
                i = i+1;
            end
            saveas(app.Save.FitFig,[directory filename num2str(i)],'jpg');
        
            filename = '\ResPlot';
            i = 0;
            while exist([directory filename num2str(i) '.jpg'], 'file') ~= 0
                i = i+1;
            end
            saveas(app.Save.ResFig,[directory filename num2str(i) '.jpg'])
            app.Func.SetDone(app);
        end

        % PlotAllButton button pushed function
        function PlotAllButton_Clicked(app)
            if app.ClearAxesState.Value
                cla(app.FitAxes);
                cla(app.Save.FitAxes);
                cla(app.ResAxes);
                cla(app.Save.ResAxes);
            end
            donedata = app.Func.PlotDataFcn(app);
            if app.Func.CalculateFitFcn(app)
                if strcmp(app.PlotFitButton.Enable, 'off')
                    app.PlotFitButton.Enable = 'on';
                    app.PlotResidualsButton.Enable = 'on';
                end
                donefit = app.Func.PlotFitFcn(app);
                doneres = app.Func.PlotResidualsFcn(app);
                if donedata && donefit && doneres
                    app.Func.PlotLegends(app);
                    app.Func.SetDone(app);
                end
            end
        end

        % OpenFileButton button pushed function
        function OpenFileButton_Clicked(app)
            if app.Func.OpenDataFile(app)
                app.Func.SetDone(app);
            end
        end

        % AboutButton button pushed function
        function AboutButton_Clicked(app)
            About;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'FitApp - by Tor Hadas';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn);
            setAutoResize(app, app.UIFigure, false)
            app.AxesClearButton = uibutton(app.UIFigure, 'push');
            app.AxesClearButton.ButtonPushedFcn = createCallbackFcn(app, @AxesClearButton_Clicked);
            app.AxesClearButton.Text = 'Clear Both';
            app.AxesSaveButton = uibutton(app.UIFigure, 'push');
            app.AxesSaveButton.ButtonPushedFcn = createCallbackFcn(app, @AxesSaveButton_Clicked);
            app.AxesSaveButton.Text = 'Save Plots';
            app.FitAxes = uiaxes(app.UIFigure);
            title(app.FitAxes, 'Title');
            xlabel(app.FitAxes, 'x');
            ylabel(app.FitAxes, 'y');
            app.FitCearButton = uibutton(app.UIFigure, 'push');
            app.FitCearButton.ButtonPushedFcn = createCallbackFcn(app, @FitCearButton_Clicked);
            app.FitCearButton.Text = 'Clear';
            app.FitCopyButton = uibutton(app.UIFigure, 'push');
            app.FitCopyButton.ButtonPushedFcn = createCallbackFcn(app, @FitCopyButton_Clicked);
            app.FitCopyButton.Text = 'Copy';
            app.ResCopyButton = uibutton(app.UIFigure, 'push');
            app.ResCopyButton.ButtonPushedFcn = createCallbackFcn(app, @ResCopyButton_Clicked);
            app.ResCopyButton.Text = 'Copy';
            app.ResAxes = uiaxes(app.UIFigure);
            title(app.ResAxes, 'Title Residuals');
            xlabel(app.ResAxes, 'x');
            ylabel(app.ResAxes, 'y Residuals');
            app.ResCearButton = uibutton(app.UIFigure, 'push');
            app.ResCearButton.ButtonPushedFcn = createCallbackFcn(app, @ResCearButton_Clicked);
            app.ResCearButton.Text = 'Clear';
            app.FitArgumentsPanel = uipanel(app.UIFigure);
            app.FitArgumentsPanel.Title = 'Fit Arguments';
            app.FitArgumentsPanel.Units = 'pixels';
            app.FuncArgsText = uieditfield(app.FitArgumentsPanel, 'text');
            app.FuncArgsText.Enable = 'off';
            app.FuncArgsText.Value = 'a(1) a(2) a(3)';
            app.FuncPlotButton = uibutton(app.FitArgumentsPanel, 'push');
            app.FuncPlotButton.ButtonPushedFcn = createCallbackFcn(app, @FuncPlotButton_Clicked);
            app.FuncPlotButton.Enable = 'off';
            app.FuncPlotButton.Text = 'Plot';
            app.FuncCheckBox = uicheckbox(app.FitArgumentsPanel);
            app.FuncCheckBox.ValueChangedFcn = createCallbackFcn(app, @FuncCheckBox_Changed);
            app.FuncCheckBox.Text = 'Custom Function';
            app.RangeMaxText = uieditfield(app.FitArgumentsPanel, 'numeric');
            app.RangeMaxText.Enable = 'off';
            app.RangeMaxText.Value = 20;
            app.RangeMinText = uieditfield(app.FitArgumentsPanel, 'numeric');
            app.RangeMinText.Enable = 'off';
            app.Label = uilabel(app.FitArgumentsPanel);
            app.Label.Text = '';
            app.RangeCheckBox = uicheckbox(app.FitArgumentsPanel);
            app.RangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RangeCheckBox_Clicked);
            app.RangeCheckBox.Text = 'Plot''s Range';
            app.MarkCheckBox = uicheckbox(app.FitArgumentsPanel);
            app.MarkCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkCheckBox_Changed);
            app.MarkCheckBox.Text = 'Mark Points';
            app.MarkText = uieditfield(app.FitArgumentsPanel, 'text');
            app.MarkText.Enable = 'off';
            app.MarkText.Value = 'n(1) n(2) ...';
            app.LegendsCheckBox = uicheckbox(app.FitArgumentsPanel);
            app.LegendsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendsCheckBox_Changed);
            app.LegendsCheckBox.Enable = 'off';
            app.LegendsCheckBox.Text = 'Legends';
            app.LegendsDropDown = uidropdown(app.FitArgumentsPanel);
            app.LegendsDropDown.Items = {'Text', 'TeX', 'Latex'};
            app.LegendsDropDown.Enable = 'off';
            app.LegendsDropDown.Value = 'Text';
            app.LegendsText = uieditfield(app.FitArgumentsPanel, 'text');
            app.LegendsText.Enable = 'off';
            app.LegendsText.Value = 'y(x); f(x); s(x)';
            app.FuncText = uitextarea(app.FitArgumentsPanel);
            app.FuncText.Enable = 'off';
            app.FuncText.Value = {'a(1) + a(2) .* x + a(3) .* x.^2;'};
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.DataPanel.Units = 'pixels';
            app.PasteDataButton = uibutton(app.DataPanel, 'push');
            app.PasteDataButton.ButtonPushedFcn = createCallbackFcn(app, @PasteDataButton_Clicked);
            app.PasteDataButton.Text = 'Paste Data';
            app.OpenFileButton = uibutton(app.DataPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButton_Clicked);
            app.OpenFileButton.Text = 'Open File';
            app.OpenFileText = uieditfield(app.DataPanel, 'text');
            app.OpenFileText.Value = 'Path \ FileName';
            app.PlotButtonsPanel = uipanel(app.UIFigure);
            app.PlotButtonsPanel.Title = 'Plot';
            app.PlotButtonsPanel.Units = 'pixels';
            app.PlotAllButton = uibutton(app.PlotButtonsPanel, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButton_Clicked);
            app.PlotAllButton.Enable = 'off';
            app.PlotAllButton.Text = 'Plot Data, Fit & Residuals';
            app.PlotDataButton = uibutton(app.PlotButtonsPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButton_Clicked);
            app.PlotDataButton.Enable = 'off';
            app.PlotDataButton.Text = 'Plot Data';
            app.CalculateFitButton = uibutton(app.PlotButtonsPanel, 'push');
            app.CalculateFitButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFitButton_Clicked);
            app.CalculateFitButton.Enable = 'off';
            app.CalculateFitButton.Text = 'Calculate Fit';
            app.PlotFitButton = uibutton(app.PlotButtonsPanel, 'push');
            app.PlotFitButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFitButton_Clicked);
            app.PlotFitButton.Enable = 'off';
            app.PlotFitButton.Text = 'Plot Fit';
            app.PlotResidualsButton = uibutton(app.PlotButtonsPanel, 'push');
            app.PlotResidualsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResidualsButton_Clicked);
            app.PlotResidualsButton.Enable = 'off';
            app.PlotResidualsButton.Text = 'Plot Residuals';
            app.ClearAxesState = uibutton(app.PlotButtonsPanel, 'state');
            app.ClearAxesState.Text = 'Clear Always';
            app.ClearAxesState.Value = true;
            app.ColorPickPanel = uipanel(app.UIFigure);
            app.ColorPickPanel.Title = 'Colors';
            app.ColorPickPanel.Units = 'pixels';
            app.Label2 = uilabel(app.ColorPickPanel);
            app.Label2.Text = 'Fit';
            app.Label4 = uilabel(app.ColorPickPanel);
            app.Label4.Text = 'Errors';
            app.Label6 = uilabel(app.ColorPickPanel);
            app.Label6.Text = 'Marker';
            app.Label7 = uilabel(app.ColorPickPanel);
            app.Label7.Text = 'Points';
            app.errorColorPickButton = uibutton(app.ColorPickPanel, 'push');
            app.errorColorPickButton.ButtonPushedFcn = createCallbackFcn(app, @errorColorPickButton_Clicked);
            app.errorColorPickButton.Icon = 'PickColorIcon.png';
            app.errorColorPickButton.Text = '';
            app.dotColorPickButton = uibutton(app.ColorPickPanel, 'push');
            app.dotColorPickButton.ButtonPushedFcn = createCallbackFcn(app, @dotColorPickButton_Clicked);
            app.dotColorPickButton.Icon = 'PickColorIcon.png';
            app.dotColorPickButton.Text = '';
            app.markColorPickButton = uibutton(app.ColorPickPanel, 'push');
            app.markColorPickButton.ButtonPushedFcn = createCallbackFcn(app, @markColorPickButton_Clicked);
            app.markColorPickButton.Icon = 'PickColorIcon.png';
            app.markColorPickButton.Text = '';
            app.fitColorPickButton = uibutton(app.ColorPickPanel, 'push');
            app.fitColorPickButton.ButtonPushedFcn = createCallbackFcn(app, @fitColorPickButton_Clicked);
            app.fitColorPickButton.Icon = 'PickColorIcon.png';
            app.fitColorPickButton.Text = '';
            app.TitlesPanel = uipanel(app.UIFigure);
            app.TitlesPanel.Title = 'Titles';
            app.TitlesPanel.Units = 'pixels';
            app.TitleText = uieditfield(app.TitlesPanel, 'text');
            app.TitleText.ValueChangedFcn = createCallbackFcn(app, @TitleText_Typed);
            app.TitleText.Value = 'Title';
            app.TitleDropDown = uidropdown(app.TitlesPanel);
            app.TitleDropDown.Items = {'None', 'TeX'};
            app.TitleDropDown.ValueChangedFcn = createCallbackFcn(app, @TitleDropDown_Changed, true);
            app.TitleDropDown.Value = 'TeX';
            app.xTitleText = uieditfield(app.TitlesPanel, 'text');
            app.xTitleText.ValueChangedFcn = createCallbackFcn(app, @xTitleText_Typed);
            app.xTitleText.Value = 'x';
            app.yTitleText = uieditfield(app.TitlesPanel, 'text');
            app.yTitleText.ValueChangedFcn = createCallbackFcn(app, @yTitleText_Typed);
            app.yTitleText.Value = 'y';
            app.yResTitleText = uieditfield(app.TitlesPanel, 'text');
            app.yResTitleText.ValueChangedFcn = createCallbackFcn(app, @yResTitleText_Typed);
            app.yResTitleText.Value = 'y Residuals';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.OutputPanel.Units = 'pixels';
            app.OutputCopyButton = uibutton(app.OutputPanel, 'push');
            app.OutputCopyButton.ButtonPushedFcn = createCallbackFcn(app, @OutputCopyButton_Clicked);
            app.OutputCopyButton.Text = 'Copy';
            app.OutputText = uitextarea(app.OutputPanel);
            app.OutputText.Editable = 'off';
            app.Label8 = uilabel(app.OutputPanel);
            app.Label8.Text = 'χ² reduced =';
            app.Label9 = uilabel(app.OutputPanel);
            app.Label9.Text = 'p-value      = ';
            app.chiLabel = uilabel(app.OutputPanel);
            app.chiLabel.Text = '1';
            app.pvalueLabel = uilabel(app.OutputPanel);
            app.pvalueLabel.Text = '0.5';
            app.OutputRelText = uitextarea(app.OutputPanel);
            app.OutputRelText.Editable = 'off';
            app.OutputRelText.Value = {''; ''};
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Units = 'pixels';
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Text = 'Status';
            app.StatusTextPanel = uipanel(app.UIFigure);
            app.StatusTextPanel.Units = 'pixels';
            app.StatusTextLabel = uilabel(app.StatusTextPanel);
            app.StatusTextLabel.Text = '';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Units = 'pixels';
            app.Label11 = uilabel(app.Panel2);
            app.Label11.Text = 'Tor Hadas';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Units = 'pixels';
            app.AboutButton = uibutton(app.Panel, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButton_Clicked);
            app.AboutButton.Text = '1.0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FitApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end